classdef imclip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FileMenu               matlab.ui.container.Menu
        CopyboundsMenu         matlab.ui.container.Menu
        ExporttoworkspaceMenu  matlab.ui.container.Menu
        AxisMenu               matlab.ui.container.Menu
        ijMenu                 matlab.ui.container.Menu
        xyMenu                 matlab.ui.container.Menu
        normalMenu             matlab.ui.container.Menu
        tightMenu              matlab.ui.container.Menu
        equalMenu              matlab.ui.container.Menu
        imageMenu              matlab.ui.container.Menu
        squareMenu             matlab.ui.container.Menu
        fillMenu               matlab.ui.container.Menu
        ColormapMenu           matlab.ui.container.Menu
        ViridisMenu            matlab.ui.container.Menu
        ParulaMenu             matlab.ui.container.Menu
        JetMenu                matlab.ui.container.Menu
        GrayMenu               matlab.ui.container.Menu
        RedBlueMenu            matlab.ui.container.Menu
        PlasmaMenu             matlab.ui.container.Menu
        Axes                   matlab.ui.control.UIAxes
        Slider2                matlab.ui.control.Slider
        Colorbar               matlab.ui.control.UIAxes
        Slider1                matlab.ui.control.Slider
    end


    properties (Access = public)
        Image % Image handle
        ColorbarImage % Colorbar image handle
    end
    properties (SetAccess = private)
        X % numeric 2d matrix to display
        Bounds % [lower, upper] in raw values
    end

    properties (Access = private)
        txtBound1 % Text bound label
        txtBound2 % Text bound label
    end

    methods (Access = public)
    
        function clip(app, bounds)
            if numel(bounds) ~= 2; error('Bounds must be specified in the format [lower, upper].'); end
            
            bounds(isinf(bounds) & bounds < 0) = min(app.X(:));
            bounds(isinf(bounds) & bounds > 0) = max(app.X(:));
            app.Bounds = bounds;
            
            caxis(app.Axes,app.Bounds)
            caxis(app.Colorbar,app.Bounds)
            
            app.txtBound1.String = num2str(app.Bounds(1),'%.3g');
            app.txtBound1.Position(2) = app.Bounds(1);
            app.txtBound2.String = num2str(app.Bounds(2),'%.3g');
            app.txtBound2.Position(2) = app.Bounds(2);
            
%             cm = rgb2gray(colormap(app.Colorbar)); cm = cm(:,1);
            
            
%             if cm_bounds(1) < 0.5; app.txtBound1.Color = 'w'; else; app.txtBound1.Color = 'k'; end
%             if cm_bounds(2) < 0.5; app.txtBound2.Color = 'w'; else; app.txtBound2.Color = 'k'; end
            
            app.UIFigure.Name = sprintf('Image Color Clipper [%.3g, %.3g]', app.Bounds(1), app.Bounds(2));
            
            drawnow;
        end
        function clipprc(app, bounds)
            if numel(bounds) ~= 2; error('Bounds must be specified in the format [lower, upper].'); end
            bounds = clip(bounds,[0 100]);
            app.clip(prctile(app.X(:),bounds));
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, X)
            if nargin < 2; X = peaks(100); end
            app.X = X;
            app.Bounds = [min(X(:)), max(X(:))];
            N = min(nunique(app.X(:)),128);
            
            app.Slider1.Limits = app.Bounds;
            app.Slider2.Limits = app.Bounds;
            
            app.Slider1.Value = app.Bounds(1);
            app.Slider2.Value = app.Bounds(2);
            
            app.Image = imagesc(app.Axes,app.X);
            axis(app.Axes,'tight','equal')
            colormap(app.Axes,viridis(N))
            
            cdata = linspace(app.Bounds(1),app.Bounds(2),N)';
            app.ColorbarImage = imagesc(app.Colorbar,[],cdata,cdata);
            axis(app.Colorbar,'tight','xy')
            colormap(app.Colorbar,viridis(N))
            
%             cdata2 = linspace(app.Bounds(1),app.Bounds(2),10)';
%             clabels = af(@(x)num2str(x,'%.2g'),cdata2);
%             hold(app.Colorbar,'on')
%             for i = 1:numel(clabels)
%                 text(app.Colorbar, mean(xlim(app.Colorbar)),cdata2(i),clabels{i},...
%                     'HorizontalAlignment','center','VerticalAlignment','middle',...
%                     'FontSize',10,'Color','k')
%             end
            
            app.txtBound1 = text(app.Colorbar, mean(xlim(app.Colorbar)), app.Bounds(1),...
                num2str(app.Bounds(1),'%.3g'),...
                'HorizontalAlignment','center','VerticalAlignment','bottom',...
                'FontSize',10,'Color','w');
            app.txtBound2 = text(app.Colorbar, mean(xlim(app.Colorbar)), app.Bounds(2),...
                num2str(app.Bounds(2),'%.3g'),...
                'HorizontalAlignment','center','VerticalAlignment','top',...
                'FontSize',10,'Color','k');

            app.UIFigure.Name = sprintf('Image Color Clipper [%.3g, %.3g]', app.Bounds(1), app.Bounds(2));
        end

        % Value changing function: Slider1, Slider2
        function SliderValueChanged(app, event)
            bnds = [app.Slider1.Value, app.Slider2.Value];            
            if event.Source == app.Slider1; bnds(1) = event.Value; end
            if event.Source == app.Slider2; bnds(2) = event.Value; end
            if bnds(1) == bnds(2); return; end
            app.clip(sort(bnds));
        end

        % Menu selected function: GrayMenu, JetMenu, ParulaMenu, 
        % PlasmaMenu, RedBlueMenu, ViridisMenu
        function ColormapSelected(app, event)
            cmap_fn = lower(event.Source.Text);
            N = nunique(app.ColorbarImage.CData);
            cmap = feval(cmap_fn,N);
            colormap(app.Colorbar,cmap)
            colormap(app.Axes,cmap)
            
            set(app.ColormapMenu.Children,'Checked','off')
            set(event.Source,'Checked','on')
        end

        % Menu selected function: ExporttoworkspaceMenu
        function ExporttoworkspaceMenuSelected(app, event)
            export2wsdlg({'Clipped image:', 'Bounds:'}, {'clipped','clims'}, {clip(app.X,app.Bounds), app.Bounds},'Export data');
        end

        % Menu selected function: ijMenu, xyMenu
        function axisYdirectionMenuSelected(app, event)
            set([app.ijMenu, app.xyMenu],'Checked','off')
            event.Source.Checked = 'on';
            axis(app.Axes,event.Source.Text)
        end

        % Menu selected function: equalMenu, fillMenu, imageMenu, 
        % normalMenu, squareMenu, tightMenu
        function axisStyleMenuSelected(app, event)
            axis(app.Axes,event.Source.Text)
        end

        % Menu selected function: CopyboundsMenu
        function CopyboundsMenuSelected(app, event)
            clipboard('copy',mat2str(app.Bounds))
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Image Color Clipper';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.CopyboundsMenu = uimenu(app.FileMenu);
            app.CopyboundsMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyboundsMenuSelected, true);
            app.CopyboundsMenu.Accelerator = 'c';
            app.CopyboundsMenu.Text = 'Copy bounds';
            app.ExporttoworkspaceMenu = uimenu(app.FileMenu);
            app.ExporttoworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoworkspaceMenuSelected, true);
            app.ExporttoworkspaceMenu.Accelerator = 's';
            app.ExporttoworkspaceMenu.Text = 'Export to workspace...';
            app.AxisMenu = uimenu(app.UIFigure);
            app.AxisMenu.Text = 'Axis';
            app.ijMenu = uimenu(app.AxisMenu);
            app.ijMenu.MenuSelectedFcn = createCallbackFcn(app, @axisYdirectionMenuSelected, true);
            app.ijMenu.Checked = 'on';
            app.ijMenu.Text = 'ij';
            app.xyMenu = uimenu(app.AxisMenu);
            app.xyMenu.MenuSelectedFcn = createCallbackFcn(app, @axisYdirectionMenuSelected, true);
            app.xyMenu.Text = 'xy';
            app.normalMenu = uimenu(app.AxisMenu);
            app.normalMenu.MenuSelectedFcn = createCallbackFcn(app, @axisStyleMenuSelected, true);
            app.normalMenu.Separator = 'on';
            app.normalMenu.Text = 'normal';
            app.tightMenu = uimenu(app.AxisMenu);
            app.tightMenu.MenuSelectedFcn = createCallbackFcn(app, @axisStyleMenuSelected, true);
            app.tightMenu.Text = 'tight';
            app.equalMenu = uimenu(app.AxisMenu);
            app.equalMenu.MenuSelectedFcn = createCallbackFcn(app, @axisStyleMenuSelected, true);
            app.equalMenu.Text = 'equal';
            app.imageMenu = uimenu(app.AxisMenu);
            app.imageMenu.MenuSelectedFcn = createCallbackFcn(app, @axisStyleMenuSelected, true);
            app.imageMenu.Text = 'image';
            app.squareMenu = uimenu(app.AxisMenu);
            app.squareMenu.MenuSelectedFcn = createCallbackFcn(app, @axisStyleMenuSelected, true);
            app.squareMenu.Text = 'square';
            app.fillMenu = uimenu(app.AxisMenu);
            app.fillMenu.MenuSelectedFcn = createCallbackFcn(app, @axisStyleMenuSelected, true);
            app.fillMenu.Text = 'fill';
            app.ColormapMenu = uimenu(app.UIFigure);
            app.ColormapMenu.Text = 'Colormap';
            app.ViridisMenu = uimenu(app.ColormapMenu);
            app.ViridisMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapSelected, true);
            app.ViridisMenu.Checked = 'on';
            app.ViridisMenu.Text = 'Viridis';
            app.ParulaMenu = uimenu(app.ColormapMenu);
            app.ParulaMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapSelected, true);
            app.ParulaMenu.Text = 'Parula';
            app.JetMenu = uimenu(app.ColormapMenu);
            app.JetMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapSelected, true);
            app.JetMenu.Text = 'Jet';
            app.GrayMenu = uimenu(app.ColormapMenu);
            app.GrayMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapSelected, true);
            app.GrayMenu.Text = 'Gray';
            app.RedBlueMenu = uimenu(app.ColormapMenu);
            app.RedBlueMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapSelected, true);
            app.RedBlueMenu.Text = 'RedBlue';
            app.PlasmaMenu = uimenu(app.ColormapMenu);
            app.PlasmaMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapSelected, true);
            app.PlasmaMenu.Text = 'Plasma';
            app.Axes = uiaxes(app.UIFigure);
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.MajorTicks = [];
            app.Slider2.MajorTickLabels = {};
            app.Slider2.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider2.MinorTicks = [];
            app.Slider2.Value = 100;
            app.Colorbar = uiaxes(app.UIFigure);
            app.Colorbar.XTick = [];
            app.Colorbar.YTick = [];
            app.Slider1 = uislider(app.UIFigure);
            app.Slider1.MajorTicks = [];
            app.Slider1.MajorTickLabels = {};
            app.Slider1.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider1.MinorTicks = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = imclip(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end